classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button                matlab.ui.control.Button
        TopVisionIsraelLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes2_2             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Propertie
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
                        % hObject    handle to ImSelect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename,pathname] = uigetfile('*.jpg','*.png');
            selected=sprintf('%s',filename);
            myImage = imread(selected);
            [status,hsv,image_ans]=Identify(myImage);
            imshow(myImage,'Parent',app.UIAxes);
            imshow(hsv,'Parent',app.UIAxes2);
            imshow(image_ans,'Parent',app.UIAxes_2);
            imshow(status,'Parent',app.UIAxes2_2);
            clear variables;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'הכנס תמונה';
            app.TopVisionIsraelLabel = uilabel(app.UIFigure);
            app.TopVisionIsraelLabel.Text = 'TopVisionIsrael';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'original Image')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gray image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Your Fruit')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Status')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end